classdef PlantSeg2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        OpenDirButton                matlab.ui.control.Button
        MiscTab                      matlab.ui.container.Tab
        rootLabel                    matlab.ui.control.Label
        shootLabel                   matlab.ui.control.Label
        ColorSelectorMaskRoot        wt.ColorSelector
        ColorSelectorMaskShoot       wt.ColorSelector
        backgroundLabel              matlab.ui.control.Label
        ColorSelectorMaskBackground  wt.ColorSelector
        ShowmaskbackgroundcolorCheckBox  matlab.ui.control.CheckBox
        UIAxesSeg                    matlab.ui.control.UIAxes
    end

    
    % PPrivate variables:
    properties (Access = private)
        bgMaskValue = 0;    
        shootMaskValue = 127;
        rootMaskValue = 255;
        bgColor = [0, 0, 0];
        shootColor = [0, 0, 1];
        rootColor = [1, 0, 0];
        inputImage = 0;         % input image with 3 channels RGB of uint8
        mask = 0;               % uint8 mask
        maskAlpha = 0.5;        % mask transparency
        showBackgroundColor = false;
    end
    
    % Private functions:
    methods (Access = private)
        
        function UpdateSegmentation(app)
            imshow(app.inputImage, "Parent", app.UIAxesSeg);
            hold(app.UIAxesSeg, "on");
            h = imshow(app.mask, "Parent", app.UIAxesSeg);
            segColorMap = [app.bgColor; app.shootColor; app.rootColor];
            colormap(app.UIAxesSeg, segColorMap);
            hold(app.UIAxesSeg, "off")

            if app.showBackgroundColor
                alphaImage = ones(size(app.mask)) * app.maskAlpha;
            else
                alphaImage = (single(app.mask > app.bgMaskValue)) * app.maskAlpha;
            end
            set(h, 'AlphaData', alphaImage);
            set(h, 'Tag', 'allData')
        end
        
        function InitializeVariables(app)
            app.Slider.Value = app.maskAlpha;
            app.ShowmaskbackgroundcolorCheckBox.Value = app.showBackgroundColor;
            app.ColorSelectorMaskBackground.Value = app.bgColor;
            app.ColorSelectorMaskShoot.Value = app.shootColor;
            app.ColorSelectorMaskRoot.Value = app.rootColor;
        end
        
        function ComputeMaskFromInput(app)
            app.mask = rgb2gray(app.inputImage);
            app.mask = (uint8(app.mask>=100 & app.mask<=149)*127) + (uint8(app.mask>=150 & app.mask<=255)*255);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenDirButton
        function OpenDirButtonPushed(app, event)
            
            app.inputImage = imread("00220110719002.tif");
            
            ComputeMaskFromInput(app);
            
            InitializeVariables(app);
            UpdateSegmentation(app);
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.maskAlpha = value;
            UpdateSegmentation(app);
        end

        % Value changed function: ShowmaskbackgroundcolorCheckBox
        function ShowmaskbackgroundcolorCheckBoxValueChanged(app, event)
            app.showBackgroundColor = app.ShowmaskbackgroundcolorCheckBox.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskBackground
        function ColorSelectorMaskBackgroundValueChanged(app, event)
            app.bgColor = app.ColorSelectorMaskBackground.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskShoot
        function ColorSelectorMaskShootValueChanged(app, event)
            app.shootColor = app.ColorSelectorMaskShoot.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskRoot
        function ColorSelectorMaskRootValueChanged(app, event)
            app.rootColor = app.ColorSelectorMaskRoot.Value;
            UpdateSegmentation(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSeg = uiaxes(app.UIFigure);
            app.UIAxesSeg.XLimitMethod = 'tight';
            app.UIAxesSeg.YLimitMethod = 'tight';
            app.UIAxesSeg.ZLimitMethod = 'tight';
            app.UIAxesSeg.XTick = [];
            app.UIAxesSeg.YTick = [];
            app.UIAxesSeg.ZTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OpenDirButton = uibutton(app.MainTab, 'push');
            app.OpenDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirButtonPushed, true);
            app.OpenDirButton.Text = 'Open Dir';
            app.SliderLabel = uilabel(app.MainTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.MainTab);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc';
            app.ShowmaskbackgroundcolorCheckBox = uicheckbox(app.MiscTab);
            app.ShowmaskbackgroundcolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmaskbackgroundcolorCheckBoxValueChanged, true);
            app.ShowmaskbackgroundcolorCheckBox.Text = 'Show mask background color';
            app.ColorSelectorMaskBackground = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskBackground.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskBackgroundValueChanged, true);
            app.backgroundLabel = uilabel(app.MiscTab);
            app.backgroundLabel.Text = 'background';
            app.ColorSelectorMaskShoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskShoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskShootValueChanged, true);
            app.ColorSelectorMaskRoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskRoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskRootValueChanged, true);
            app.shootLabel = uilabel(app.MiscTab);
            app.shootLabel.Text = 'shoot';
            app.rootLabel = uilabel(app.MiscTab);
            app.rootLabel.Text = 'root';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantSeg2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end